classdef HolmesOliver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ExportDataTableButton       matlab.ui.control.Button
        ExportPlotButton            matlab.ui.control.Button
        ModelParametersPanel        matlab.ui.container.Panel
        AzimuthField                matlab.ui.control.NumericEditField
        AzimuthSlider               matlab.ui.control.Slider
        AzimuthpositionintheoutflowdegreeLabel  matlab.ui.control.Label
        intensificationtimeField    matlab.ui.control.NumericEditField
        intensificationtimeSlider   matlab.ui.control.Slider
        TimelengthofstormintensificationminLabel  matlab.ui.control.Label
        timeconstantField           matlab.ui.control.NumericEditField
        timeconstantSlider          matlab.ui.control.Slider
        TimeconstantminLabel        matlab.ui.control.Label
        timeelapsedField            matlab.ui.control.NumericEditField
        timeelapsedSlider           matlab.ui.control.Slider
        CurrenttimeofstormminLabel  matlab.ui.control.Label
        radiallengthField           matlab.ui.control.NumericEditField
        radiallengthSlider          matlab.ui.control.Slider
        RadiallengthscalekmLabel    matlab.ui.control.Label
        rmaxField                   matlab.ui.control.NumericEditField
        MaxhorizontalvelField       matlab.ui.control.NumericEditField
        MaxhorizontalvelSlider      matlab.ui.control.Slider
        MaximumhorizontalvelocitymsLabel  matlab.ui.control.Label
        rmaxSlider                  matlab.ui.control.Slider
        RadiusofmaximumhorizontalvelocitykmLabel  matlab.ui.control.Label
        HolmesOliver2000Label       matlab.ui.control.Label
        HorizontalAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Selection window
    end
    
    methods (Access = private)

        function u_r_total = compute_ur(app, r, theta, ur_max, r_max, R, t, T)
            if r < app.rmaxField.Value
                % Horizontal downburst velocity
                u_r = ur_max * exp(-t/T) .* (r./r_max);
                            
                % u and v components of radial downburst velocity
                u = u_r*cos(theta);
                v = u_r*sin(theta);

                % Calculate the magnitude of new radial downburst velocity
                u_r_total = sqrt(u_r.^2 + v.^2);
            else
                % Horizontal downburst velocity
                u_r = ur_max * exp(-t/T) .* exp(-((r-r_max)./R).^2);

                % u and v components of radial downburst velocity
                u = u_r*cos(theta);
                v = u_r*sin(theta);

                % Calculate the magnitude of new radial downburst velocity
                u_r_total = sqrt(u_r.^2 + v.^2);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller6)
            % Store selection menu app object
            app.CallingApp = caller6;

            % Define parameters for radial profile
            theta = app.AzimuthField.Value;
            r = 0:0.1:10000;
            ur_max = app.MaxhorizontalvelField.Value;
            r_max = app.rmaxField.Value * 1000;
            R = app.radiallengthField.Value * 1000;
            t_intf = app.intensificationtimeField.Value;
            t = app.timeelapsedField.Value - t_intf;
            T = app.timeconstantField.Value;

            % Plot radial profile 
            plot(app.HorizontalAxes, r, compute_ur(app, r, theta, ur_max, r_max, R, t, T), 'k');
            p = app.HorizontalAxes.Children;
            p.LineWidth = 1.5;

            % Adjust axis for radial profile
            r_main = 2 * r_max;
            xlim(app.HorizontalAxes, [0, (r_main+1000)]);
        end

        % Value changed function: AzimuthField, MaxhorizontalvelField, 
        % intensificationtimeField, radiallengthField, rmaxField, 
        % timeconstantField, timeelapsedField
        function FieldValueChanged(app, event)
            % Define parameters for radial profile
            theta = app.AzimuthField.Value;
            r = 0:0.1:10000;
            ur_max = app.MaxhorizontalvelField.Value;
            r_max = app.rmaxField.Value * 1000;
            R = app.radiallengthField.Value * 1000;
            t_intf = app.intensificationtimeField.Value;
            t = app.timeelapsedField.Value - t_intf;
            T = app.timeconstantField.Value;

            % Plot radial velocity
            plot(app.HorizontalAxes, r, compute_ur(app, r, theta, ur_max, r_max, R, t, T), 'k');
            p = app.HorizontalAxes.Children;
            p.LineWidth = 1.5;

            % Adjust axis for radial profile
            r_main = 2 * r_max;
            xlim(app.HorizontalAxes, [0, (r_main+1000)]);

            % Update sliders to match field values
            if app.AzimuthField.Value ~= app.AzimuthSlider.Value
                app.AzimuthSlider.Value = app.AzimuthField.Value;
            else
            end
            if app.MaxhorizontalvelField.Value ~= app.MaxhorizontalvelSlider.Value
                app.MaxhorizontalvelSlider.Value = app.MaxhorizontalvelField.Value;
            else 
            end
            if app.rmaxField.Value ~= app.rmaxSlider.Value
                app.rmaxSlider.Value = app.rmaxField.Value;
            else
            end
            if app.radiallengthField.Value ~= app.radiallengthSlider.Value
                app.radiallengthSlider.Value = app.radiallengthField.Value;
            else
            end
            if app.intensificationtimeField.Value ~= app.intensificationtimeSlider.Value
                app.intensificationtimeSlider.Value = app.intensificationtimeField.Value;
            else
            end
            if app.timeelapsedField.Value ~= app.timeelapsedSlider.Value
                app.timeelapsedSlider.Value = app.timeelapsedField.Value;
            else
            end
            if app.timeconstantField.Value ~= app.timeconstantSlider.Value
                app.timeconstantSlider.Value = app.timeconstantField.Value;
            else
            end
        end

        % Value changed function: AzimuthSlider, MaxhorizontalvelSlider, 
        % intensificationtimeSlider, radiallengthSlider, rmaxSlider, 
        % timeconstantSlider, timeelapsedSlider
        function SliderValueChanged(app, event)
            % Define parameters for radial profile
            theta = app.AzimuthSlider.Value;
            r = 0:0.1:10000;
            ur_max = app.MaxhorizontalvelSlider.Value;
            r_max = app.rmaxSlider.Value * 1000;
            R = app.radiallengthSlider.Value * 1000;
            t_intf = app.intensificationtimeSlider.Value;
            t = app.timeelapsedSlider.Value - t_intf;
            T = app.timeconstantSlider.Value;

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_ur(app, r, theta, ur_max, r_max, R, t, T), 'k');
            p = app.HorizontalAxes.Children;
            p.LineWidth = 1.5;

            % Adjust axis for radial profile
            r_main = 2 * r_max;
            xlim(app.HorizontalAxes, [0, (r_main+1000)]);
            
            % Update field values to match sliders
            if app.AzimuthSlider.Value ~= app.AzimuthField.Value
                app.AzimuthField.Value = app.AzimuthSlider.Value;
            else
            end
            if app.MaxhorizontalvelSlider.Value ~= app.MaxhorizontalvelField.Value
                app.MaxhorizontalvelField.Value = app.MaxhorizontalvelSlider.Value;
            else
            end  
            if app.rmaxSlider.Value ~= app.rmaxField.Value
                app.rmaxField.Value = app.rmaxSlider.Value;            
            else
            end
            if app.radiallengthSlider.Value ~= app.radiallengthField.Value
                app.radiallengthField.Value = app.radiallengthSlider.Value;
            else
            end
            if app.intensificationtimeSlider.Value ~= app.intensificationtimeField.Value
                app.intensificationtimeField.Value = app.intensificationtimeSlider.Value;
            else
            end
            if app.timeelapsedSlider.Value ~= app.timeelapsedField.Value
                app.timeelapsedField.Value = app.timeelapsedSlider.Value;
            else
            end
            if app.timeconstantSlider.Value ~= app.timeconstantField.Value
                app.timeconstantField.Value = app.timeconstantSlider.Value;
            else
            end
        end

        % Button pushed function: ExportPlotButton
        function ExportPlotButtonPushed(app, event)
            % Create a temporary figure of the plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.HorizontalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.HorizontalAxes.XLim;
            figAxes.YLim = app.HorizontalAxes.YLim;
            figAxes.ZLim = app.HorizontalAxes.ZLim;
            figAxes.DataAspectRatio = app.HorizontalAxes.DataAspectRatio;
            xlabel(figAxes, 'Distance from downdraft center [m]');
            ylabel(figAxes, 'Velocity [m/s]');
            title(figAxes, 'Profile of Radial Velocity');

            % Save plot as png file
            saveas(fig, 'HolmesOliver_profile_plot', 'png');

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton
        function ExportDataTableButtonPushed(app, event)
            % Define parameters for radial profile
            theta = app.AzimuthField.Value;
            r = 0:0.1:10000;
            ur_max = app.MaxhorizontalvelField.Value;
            r_max = app.rmaxField.Value * 1000;
            R = app.radiallengthField.Value * 1000;
            t_intf = app.intensificationtimeField.Value;
            t = app.timeelapsedField.Value - t_intf;
            T = app.timeconstantField.Value;

            % Put x axis values into an array
            r = [0.1:0.1:1000];
            % Compute velocities and insert into arrays
            m = [compute_ur(app, r, theta, ur_max, r_max, R, t, T)];

            % Arrange arrays into a table
            ar = [r; m];
            t = array2table(ar.');
            t = renamevars(t, ["Var1", "Var2"], ["Distance from downdraft center [m]", "Velocity [m/s]",]);
            
            % Write table into csv file
            writetable(t, 'HolmesOliver_profile_table.csv')
        end

        % Close request function: UIFigure
        function HolmesOliverCloseRequest(app, event)
            % Reenable Holmes & Oliver button
            app.CallingApp.HolmesOliver2000Button.Enable = 'on';

            % Close Holmes & Oliver window
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @HolmesOliverCloseRequest, true);
            app.HorizontalAxes = uiaxes(app.UIFigure);
            title(app.HorizontalAxes, 'Profile of Radial Velocity')
            xlabel(app.HorizontalAxes, 'Distance from downdraft center [m]')
            ylabel(app.HorizontalAxes, 'Velocity [m/s]')
            zlabel(app.HorizontalAxes, 'Z')
            app.HolmesOliver2000Label = uilabel(app.UIFigure);
            app.HolmesOliver2000Label.Text = 'Holmes & Oliver (2000)';
            app.ModelParametersPanel = uipanel(app.UIFigure);
            app.ModelParametersPanel.Title = 'Model Parameters';
            app.RadiusofmaximumhorizontalvelocitykmLabel = uilabel(app.ModelParametersPanel);
            app.RadiusofmaximumhorizontalvelocitykmLabel.Text = 'Radius of maximum horizontal velocity [km]';
            app.rmaxSlider = uislider(app.ModelParametersPanel);
            app.rmaxSlider.Limits = [0 3];
            app.rmaxSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.rmaxSlider.Value = 1;
            app.MaximumhorizontalvelocitymsLabel = uilabel(app.ModelParametersPanel);
            app.MaximumhorizontalvelocitymsLabel.Text = 'Maximum horizontal velocity [m/s]';
            app.MaxhorizontalvelSlider = uislider(app.ModelParametersPanel);
            app.MaxhorizontalvelSlider.Limits = [0 60];
            app.MaxhorizontalvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxhorizontalvelSlider.Value = 40;
            app.MaxhorizontalvelField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.MaxhorizontalvelField.Limits = [0 60];
            app.MaxhorizontalvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxhorizontalvelField.Value = 40;
            app.rmaxField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.rmaxField.Limits = [0 3];
            app.rmaxField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.rmaxField.Value = 1;
            app.RadiallengthscalekmLabel = uilabel(app.ModelParametersPanel);
            app.RadiallengthscalekmLabel.Text = 'Radial length scale [km]';
            app.radiallengthSlider = uislider(app.ModelParametersPanel);
            app.radiallengthSlider.Limits = [0 3];
            app.radiallengthSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.radiallengthSlider.Value = 0.5;
            app.radiallengthField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.radiallengthField.Limits = [0 3];
            app.radiallengthField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.radiallengthField.Value = 0.5;
            app.CurrenttimeofstormminLabel = uilabel(app.ModelParametersPanel);
            app.CurrenttimeofstormminLabel.Text = 'Current time of storm [min]';
            app.timeelapsedSlider = uislider(app.ModelParametersPanel);
            app.timeelapsedSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.timeelapsedSlider.Value = 30;
            app.timeelapsedField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.timeelapsedField.Limits = [0 100];
            app.timeelapsedField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.timeelapsedField.Value = 30;
            app.TimeconstantminLabel = uilabel(app.ModelParametersPanel);
            app.TimeconstantminLabel.Text = 'Time constant [min]';
            app.timeconstantSlider = uislider(app.ModelParametersPanel);
            app.timeconstantSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.timeconstantSlider.Value = 30;
            app.timeconstantField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.timeconstantField.Limits = [0 100];
            app.timeconstantField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.timeconstantField.Value = 30;
            app.TimelengthofstormintensificationminLabel = uilabel(app.ModelParametersPanel);
            app.TimelengthofstormintensificationminLabel.Text = 'Time length of storm intensification [min]';
            app.intensificationtimeSlider = uislider(app.ModelParametersPanel);
            app.intensificationtimeSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.intensificationtimeSlider.Value = 20;
            app.intensificationtimeField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.intensificationtimeField.Limits = [0 100];
            app.intensificationtimeField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.intensificationtimeField.Value = 20;
            app.AzimuthpositionintheoutflowdegreeLabel = uilabel(app.ModelParametersPanel);
            app.AzimuthpositionintheoutflowdegreeLabel.Text = 'Azimuth position in the outflow [degree]';
            app.AzimuthSlider = uislider(app.ModelParametersPanel);
            app.AzimuthSlider.Limits = [0 180];
            app.AzimuthSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.AzimuthSlider.Value = 45;
            app.AzimuthField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.AzimuthField.Limits = [0 180];
            app.AzimuthField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.AzimuthField.Value = 45;
            app.ExportPlotButton = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton.Text = {'Export Plot'; ''};
            app.ExportDataTableButton = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButtonPushed, true);
            app.ExportDataTableButton.Text = {'Export Data Table'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HolmesOliver(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end