classdef OBV_Chay < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_3                         matlab.ui.container.Panel
        FixedParametersCheckBox         matlab.ui.control.CheckBox
        Panel_2                         matlab.ui.container.Panel
        rField                          matlab.ui.control.NumericEditField
        rSlider                         matlab.ui.control.Slider
        DistancefromdowndraftcenterkmLabel  matlab.ui.control.Label
        VerticalProfileModelParametersPanel  matlab.ui.container.Panel
        rtField_2                       matlab.ui.control.NumericEditField
        rtSlider_2                      matlab.ui.control.Slider
        TimedependentradiusofmaximumoutflowvelocitykmLabel_2  matlab.ui.control.Label
        AzimuthField_2                  matlab.ui.control.NumericEditField
        AzimuthSlider_2                 matlab.ui.control.Slider
        AzimuthpositionintheoutflowdegreeLabel_2  matlab.ui.control.Label
        utransField_2                   matlab.ui.control.NumericEditField
        utransSlider_2                  matlab.ui.control.Slider
        TranslationalvelocityofstormmsLabel_2  matlab.ui.control.Label
        TimeofstormSlider_2             matlab.ui.control.Slider
        TimeofstormField_2              matlab.ui.control.NumericEditField
        TimeofstormSliderLabel_2        matlab.ui.control.Label
        IntensificationtimeField_2      matlab.ui.control.NumericEditField
        IntensificationtimeSlider_2     matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel_2  matlab.ui.control.Label
        ShapingfunctionvariableField_2  matlab.ui.control.NumericEditField
        ShapingfunctionvariableSlider_2  matlab.ui.control.Slider
        ShapingfunctionvariableSliderLabel_2  matlab.ui.control.Label
        MaxrSlider_2                    matlab.ui.control.Slider
        RadiusofmaximumoutflowvelocitykmLabel_2  matlab.ui.control.Label
        MaxrField_2                     matlab.ui.control.NumericEditField
        MaxoutflowvelField_2            matlab.ui.control.NumericEditField
        MaxoutflowvelSlider_2           matlab.ui.control.Slider
        MaximumoutflowvelocitymsLabel_2  matlab.ui.control.Label
        MaxzField_2                     matlab.ui.control.NumericEditField
        MaxzSlider_2                    matlab.ui.control.Slider
        HeightofmaximumoutflowvelocitymLabel_2  matlab.ui.control.Label
        ExportDataTableButton_3         matlab.ui.control.Button
        ExportPlotButton_3              matlab.ui.control.Button
        ExportDataTableButton_2         matlab.ui.control.Button
        ExportPlotButton_2              matlab.ui.control.Button
        PlottingOptionsPanel            matlab.ui.container.Panel
        PlotverticalvelocityCheckBox    matlab.ui.control.CheckBox
        PlothorizontalvelocityCheckBox  matlab.ui.control.CheckBox
        PlotmagnitudeCheckBox           matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        zField                          matlab.ui.control.NumericEditField
        zSlider                         matlab.ui.control.Slider
        zmLabel                         matlab.ui.control.Label
        ModelParametersPanel            matlab.ui.container.Panel
        rtField                         matlab.ui.control.NumericEditField
        rtSlider                        matlab.ui.control.Slider
        TimedependentradiusofmaximumoutflowvelocitykmLabel  matlab.ui.control.Label
        AzimuthField                    matlab.ui.control.NumericEditField
        AzimuthSlider                   matlab.ui.control.Slider
        AzimuthpositionintheoutflowdegreeLabel  matlab.ui.control.Label
        utransField                     matlab.ui.control.NumericEditField
        utransSlider                    matlab.ui.control.Slider
        TranslationalvelocityofstormmsLabel  matlab.ui.control.Label
        TimeofstormSlider               matlab.ui.control.Slider
        TimeofstormField                matlab.ui.control.NumericEditField
        TimeofstormSliderLabel          matlab.ui.control.Label
        IntensificationtimeField        matlab.ui.control.NumericEditField
        IntensificationtimeSlider       matlab.ui.control.Slider
        LengthofstormintensificationminSliderLabel  matlab.ui.control.Label
        ShapingfunctionvariableField    matlab.ui.control.NumericEditField
        ShapingfunctionvariableSlider   matlab.ui.control.Slider
        ShapingfunctionvariableSliderLabel  matlab.ui.control.Label
        MaxrSlider                      matlab.ui.control.Slider
        RadiusofmaximumoutflowvelocitykmLabel  matlab.ui.control.Label
        MaxrField                       matlab.ui.control.NumericEditField
        MaxzField                       matlab.ui.control.NumericEditField
        MaxoutflowvelField              matlab.ui.control.NumericEditField
        MaxoutflowvelSlider             matlab.ui.control.Slider
        MaximumoutflowvelocitymsLabel   matlab.ui.control.Label
        MaxzSlider                      matlab.ui.control.Slider
        HeightofmaximumoutflowvelocitymLabel  matlab.ui.control.Label
        ChayAlbermaniWilson2006Label    matlab.ui.control.Label
        VerticalAxes                    matlab.ui.control.UIAxes
        HorizontalAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Selection window
    end
    
    methods (Access = private)

        function u_mag = compute_umag(app, r, z, theta, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f)
            % Horizontal downburst velocity
            u_r = i_f .* ((ur_max .* r) ./ r_t) .* ((exp(c_1 * (z/z_max)) - exp(c_2 * (z/z_max))) / (exp(c_1) - exp(c_2))) .* (exp((1 - (((r.^2)/(r_t^2)).^a)) / (2*a))) + u_trans;
    
            % u and v components of radial downburst velocity
            u = u_r*cosd(theta);
            v = u_r*sind(theta);
            
            % Combine u component of downburst velocity with the translation velocity
            u_total = u + u_trans;
            
            % Calculate the magnitude of new radial downburst velocity
            u_r_total = sqrt(u_total.^2 + v.^2);
    
            % Vertical downburst velocity
            u_z = -2 * i_f .* ((ur_max * z_max) ./ r_max) .* (((1/c_1)*(exp(c_1 * (z/z_max)) - 1) - (1/c_2)*(exp(c_2 * (z/z_max)) - 1)) / (exp(c_1) - exp(c_2))) .* (1 - (0.5 * ((r.^2/r_max^2).^a))) .* (exp((1 - (((r.^2)/(r_max^2)).^a)) / (2*a)));
    
            % Downburst velocity magnitude
            u_mag = sqrt(u_r_total.^2 + u_z.^2);
        end

        function u_r_total = compute_ur(app, r, z, theta, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f)
            % Horizontal downburst velocity
            u_r = i_f .* ((ur_max .* r) ./ r_t) .* ((exp(c_1 * (z/z_max)) - exp(c_2 * (z/z_max))) / (exp(c_1) - exp(c_2))) .* (exp((1 - (((r.^2)/(r_t^2)).^a)) / (2*a))) + u_trans;

            % u and v components of radial downburst velocity
            u = u_r*cosd(theta);
            v = u_r*sind(theta);
            
            % Combine u component of downburst velocity with the translation velocity
            u_total = u + u_trans;
            
            % Calculate the magnitude of new radial downburst velocity
            u_r_total = sqrt(u_total.^2 + v.^2);
        end
        
        function u_z = compute_uz(app, r, z, theta, ur_max, r_max, z_max, c_1, c_2, a, i_f)
            % Vertical downburst velocity
            u_z = -2 * i_f .* ((ur_max * z_max) ./ r_max) .* (((1/c_1)*(exp(c_1 * (z/z_max)) - 1) - (1/c_2)*(exp(c_2 * (z/z_max)) - 1)) / (exp(c_1) - exp(c_2))) .* (1 - (0.5 * ((r.^2/r_max^2).^a))) .* (exp((1 - (((r.^2)/(r_max^2)).^a)) / (2*a)));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller4)
            % Store selection menu app object
            app.CallingApp = caller4;
            
            % Set up plotting options menu
            app.PlotmagnitudeCheckBox.Value = 1;
            app.PlothorizontalvelocityCheckBox.Value = 1;
            app.PlotverticalvelocityCheckBox.Value = 1;
            app.FixedParametersCheckBox.Value = 1;
            
            % Define parameters for radial profile
            t = app.TimeofstormSlider.Value;
            for t_int = app.IntensificationtimeSlider.Value
            if t <= t_int
                i_f = t / t_int;
            else 
              i_f = exp((-(t-t_int))/11.542);   
            end
            end
            theta = app.AzimuthSlider.Value;
            r = 0:0.1:10000;
            z = app.zSlider.Value;
            r_max = app.MaxrSlider.Value * 1000; % Denoted as rp in paper
            z_max = app.MaxzSlider.Value;
            ur_max = app.MaxoutflowvelSlider.Value;
            u_trans = app.utransSlider.Value;
            r_t = app.rtSlider.Value * 1000;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider.Value;

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_umag(app, r, z, theta, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f), 'k', r, compute_ur(app, r, z, theta, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f), 'r', r, compute_uz(app, r, z, theta, ur_max, r_max, z_max, c_1, c_2, a, i_f), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Adjust axis for radial profile
            r_main = 2 * r_max;
            xlim(app.HorizontalAxes, [0, (r_main+1000)]);

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Define parameters for vertical profile
            t = app.TimeofstormSlider_2.Value;
            for t_int = app.IntensificationtimeSlider_2.Value
            if t <= t_int
                i_f = t / t_int;
            else 
              i_f = exp((-(t-t_int))/11.542);   
            end
            end
            theta = app.AzimuthField_2.Value * 1000;
            r = app.rField.Value * 1000;
            z = 0:0.1:1000;
            r_max = app.MaxrField_2.Value * 1000;
            z_max = app.MaxzField_2.Value;
            ur_max = app.MaxoutflowvelField_2.Value;
            u_trans = app.utransField_2.Value;
            r_t = app.rtSlider_2.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableField_2.Value;

            % Plot vertical profile
            plot(app.VerticalAxes, compute_umag(app, r, z, theta, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f), z, 'k', compute_ur(app, r, z, theta, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f), z, 'r', compute_uz(app, r, z, theta, ur_max, r_max, z_max, c_1, c_2, a, i_f), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');
        end

        % Value changed function: AzimuthSlider, 
        % IntensificationtimeSlider, MaxoutflowvelSlider, MaxrSlider, 
        % MaxzSlider, ShapingfunctionvariableSlider, TimeofstormSlider, 
        % rtSlider, utransSlider, zSlider
        function SliderValueChanged(app, event)
            % Define parameters for radial profile
            t = app.TimeofstormSlider.Value;
            for t_int = app.IntensificationtimeSlider.Value
            if t <= t_int
                i_f = t / t_int;
            else 
              i_f = exp((-(t-t_int))/11.542);   
            end
            end
            theta = app.AzimuthSlider.Value;
            r = 0:0.1:10000;
            z = app.zSlider.Value;
            r_max = app.MaxrSlider.Value * 1000;
            z_max = app.MaxzSlider.Value;
            ur_max = app.MaxoutflowvelSlider.Value;
            u_trans = app.utransSlider.Value;
            r_t = app.rtSlider.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider.Value;

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_umag(app, r, z, theta, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f), 'k', r, compute_ur(app, r, z, theta, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f), 'r', r, compute_uz(app, r, z, theta, ur_max, r_max, z_max, c_1, c_2, a, i_f), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');

            % Adjust axis for radial profile
            r_main = 2 * r_max;
            xlim(app.HorizontalAxes, [0, (r_main+1000)]);

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.AzimuthField.Value ~= app.AzimuthSlider.Value
                app.AzimuthField.Value = app.AzimuthSlider.Value;
            else
            end
            if app.MaxoutflowvelField.Value ~= app.MaxoutflowvelSlider.Value
                app.MaxoutflowvelField.Value = app.MaxoutflowvelSlider.Value;
            else
            end
            if app.MaxrField.Value ~= app.MaxrSlider.Value
                app.MaxrField.Value = app.MaxrSlider.Value;
            else
            end
            if app.MaxzField.Value ~= app.MaxzSlider.Value
                app.MaxzField.Value = app.MaxzSlider.Value;
            else
            end
            if app.utransField.Value ~= app.utransSlider.Value
                app.utransField.Value = app.utransSlider.Value;
            else
            end
            if app.IntensificationtimeField.Value ~= app.IntensificationtimeSlider.Value
                app.IntensificationtimeField.Value = app.IntensificationtimeSlider.Value;
            else
            end
            if app.TimeofstormField.Value ~= app.TimeofstormSlider.Value
                app.TimeofstormField.Value = app.TimeofstormSlider.Value;
            else
            end
            if app.ShapingfunctionvariableField.Value ~= app.ShapingfunctionvariableSlider.Value
                app.ShapingfunctionvariableField.Value = app.ShapingfunctionvariableSlider.Value;
            else
            end
            if app.zField.Value ~= app.zSlider.Value
                app.zField.Value = app.zSlider.Value;
            else
            end
            if app.rtField.Value ~= app.rtSlider.Value
                app.rtField.Value = app.rtSlider.Value;
            end

            % Update sliders if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.AzimuthField_2.Value = app.AzimuthField.Value;
                app.AzimuthSlider_2.Value = app.AzimuthSlider.Value;
                app.MaxoutflowvelField_2.Value = app.MaxoutflowvelField.Value;
                app.MaxoutflowvelSlider_2.Value = app.MaxoutflowvelSlider.Value;
                app.MaxrField_2.Value = app.MaxrField.Value; 
                app.MaxrSlider_2.Value = app.MaxrSlider.Value;
                app.MaxzField_2.Value = app.MaxzField.Value;
                app.MaxzSlider_2.Value = app.MaxzSlider.Value;
                app.utransField_2.Value = app.utransField.Value;
                app.utransSlider_2.Value = app.utransSlider.Value;
                app.IntensificationtimeField_2.Value = app.IntensificationtimeField.Value;
                app.IntensificationtimeSlider_2.Value = app.IntensificationtimeSlider.Value;
                app.TimeofstormField_2.Value = app.TimeofstormField.Value;
                app.TimeofstormSlider_2.Value = app.TimeofstormSlider.Value;
                app.ShapingfunctionvariableField_2.Value = app.ShapingfunctionvariableField.Value;
                app.ShapingfunctionvariableSlider_2.Value = app.ShapingfunctionvariableSlider.Value;
                app.rtField_2.Value = app.rtField.Value;
                app.rtSlider_2.Value = app.rtSlider.Value;

                % Define parameters for vertical profile
                t = app.TimeofstormField_2.Value;
                for t_int = app.IntensificationtimeSlider_2.Value
                if t <= t_int
                    i_f = t / t_int;
                else 
                  i_f = exp((-(t-t_int))/11.542);   
                end
                end
                theta = app.AzimuthField_2.Value;
                r = app.rField.Value * 1000;
                z = 0:0.1:1000;
                r_max = app.MaxrField_2.Value * 1000;
                z_max = app.MaxzField_2.Value;
                ur_max = app.MaxoutflowvelField_2.Value;
                u_trans = app.utransField_2.Value;
                r_t = app.rtField_2.Value;
                c_1 = -0.15;
                c_2 = -3.2175; 
                a = app.ShapingfunctionvariableField_2.Value;

                plot(app.VerticalAxes, compute_umag(app, r, z, theta, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f), z, 'k', compute_ur(app, r, z, theta, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f), z, 'r', compute_uz(app, r, z, theta, ur_max, r_max, z_max, c_1, c_2, a, i_f), z, 'b');
                p = app.VerticalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.VerticalAxes, 'Location', 'southoutside');

                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end
        end

        % Value changed function: AzimuthField, IntensificationtimeField, 
        % MaxoutflowvelField, MaxrField, MaxzField, 
        % ShapingfunctionvariableField, TimeofstormField, rtField, 
        % utransField, zField
        function FieldValueChanged(app, event)
            % Define parameters for radial profile
            t = app.TimeofstormField.Value;
            for t_int = app.IntensificationtimeField.Value
            if t <= t_int
                i_f = t / t_int;
            else 
              i_f = exp((-(t-t_int))/11.542);   
            end
            end
            theta = app.AzimuthField.Value;
            r = 0:0.1:10000;
            z = app.zField.Value;
            r_max = app.MaxrField.Value * 1000;
            z_max = app.MaxzField.Value;
            ur_max = app.MaxoutflowvelField.Value;
            u_trans = app.utransField.Value;
            r_t = app.rtField.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableField.Value;

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_umag(app, r, z, theta, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f), 'k', r, compute_ur(app, r, z, theta, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f), 'r', r, compute_uz(app, r, z, theta, ur_max, r_max, z_max, c_1, c_2, a, i_f), 'b');
            p = app.HorizontalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.HorizontalAxes, 'Location', 'southoutside');   

            % Adjust axis for radial profile
            r_main = 2 * r_max;
            xlim(app.HorizontalAxes, [0, (r_main+1000)]);
            
            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update slider values to match fields
            if app.AzimuthField.Value ~= app.AzimuthSlider.Value
                app.AzimuthSlider.Value = app.AzimuthField.Value;
            else
            end
            if app.MaxoutflowvelField.Value ~= app.MaxoutflowvelSlider.Value
                app.MaxoutflowvelSlider.Value = app.MaxoutflowvelField.Value;
            else
            end
            if app.MaxrField.Value ~= app.MaxrSlider.Value
                app.MaxrSlider.Value = app.MaxrField.Value;
            else
            end
            if app.MaxzField.Value ~= app.MaxzSlider.Value
                app.MaxzSlider.Value = app.MaxzField.Value;
            else
            end
            if app.utransField.Value ~= app.utransSlider.Value
                app.utransSlider.Value = app.utransField.Value;
            else
            end
            if app.IntensificationtimeField.Value ~= app.IntensificationtimeSlider.Value
                app.IntensificationtimeSlider.Value = app.IntensificationtimeField.Value;
            else
            end
            if app.TimeofstormField.Value ~= app.TimeofstormSlider.Value
                app.TimeofstormSlider.Value = app.TimeofstormField.Value;
            else
            end
            if app.ShapingfunctionvariableField.Value ~= app.ShapingfunctionvariableSlider.Value
                app.ShapingfunctionvariableSlider.Value = app.ShapingfunctionvariableField.Value;
            else
            end
            if app.zField.Value ~= app.zSlider.Value
                app.zSlider.Value = app.zField.Value;
            else
            end
            if app.rtField.Value ~= app.rtSlider.Value
                app.rtSlider.Value = app.rtField.Value;
            end

             % Update sliders if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.AzimuthField_2.Value = app.AzimuthField.Value;
                app.AzimuthSlider_2.Value = app.AzimuthSlider.Value;
                app.MaxoutflowvelField_2.Value = app.MaxoutflowvelField.Value;
                app.MaxoutflowvelSlider_2.Value = app.MaxoutflowvelSlider.Value;
                app.MaxrField_2.Value = app.MaxrField.Value; 
                app.MaxrSlider_2.Value = app.MaxrSlider.Value;
                app.MaxzField_2.Value = app.MaxzField.Value;
                app.MaxzSlider_2.Value = app.MaxzSlider.Value;
                app.utransField_2.Value = app.utransField.Value;
                app.utransSlider_2.Value = app.utransSlider.Value;
                app.IntensificationtimeField_2.Value = app.IntensificationtimeField.Value;
                app.IntensificationtimeSlider_2.Value = app.IntensificationtimeSlider.Value;
                app.TimeofstormField_2.Value = app.TimeofstormField.Value;
                app.TimeofstormSlider_2.Value = app.TimeofstormSlider.Value;
                app.ShapingfunctionvariableField_2.Value = app.ShapingfunctionvariableField.Value;
                app.ShapingfunctionvariableSlider_2.Value = app.ShapingfunctionvariableSlider.Value;
                app.rtField_2.Value = app.rtField.Value;
                app.rtSlider_2.Value = app.rtSlider.Value;

                t = app.TimeofstormField_2.Value;
                for t_int = app.IntensificationtimeField_2.Value
                if t <= t_int
                    i_f = t / t_int;
                else 
                  i_f = exp((-(t-t_int))/11.542);   
                end
                end    
                theta = app.AzimuthField_2.Value;
                r = app.rField.Value * 1000;
                z = 0:0.1:1000;
                r_max = app.MaxrField_2.Value * 1000;
                z_max = app.MaxzField_2.Value;
                ur_max = app.MaxoutflowvelField_2.Value;
                u_trans = app.utransField_2.Value;
                r_t = app.rtField_2.Value;
                c_1 = -0.15;
                c_2 = -3.2175; 
                a = app.ShapingfunctionvariableField_2.Value;

                plot(app.VerticalAxes, compute_umag(app, r, z, theta, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f), z, 'k', compute_ur(app, r, z, theta, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f), z, 'r', compute_uz(app, r, z, theta, ur_max, r_max, z_max, c_1, c_2, a, i_f), z, 'b');
                p = app.VerticalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.VerticalAxes, 'Location', 'southoutside');

                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end
        end

        % Value changed function: AzimuthSlider_2, 
        % IntensificationtimeSlider_2, MaxoutflowvelSlider_2, 
        % MaxrSlider_2, MaxzSlider_2, ShapingfunctionvariableSlider_2, 
        % TimeofstormSlider_2, rSlider, rtSlider_2, utransSlider_2
        function Slider2ValueChanged(app, event)
            % Define parameters for vertical profile
            t = app.TimeofstormSlider_2.Value;
            for t_int = app.IntensificationtimeSlider_2.Value
            if t <= t_int
                i_f = t / t_int;
            else 
              i_f = exp((-(t-t_int))/11.542);   
            end
            end
            theta = app.AzimuthSlider_2.Value;
            r = app.rSlider.Value * 1000;
            z = 0:0.1:1000;
            r_max = app.MaxrSlider_2.Value * 1000;
            z_max = app.MaxzSlider_2.Value;
            ur_max = app.MaxoutflowvelSlider_2.Value;
            u_trans = app.utransSlider_2.Value;
            r_t = app.rtSlider_2.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider_2.Value;

            % Plot vertical profile
            plot(app.VerticalAxes, compute_umag(app, r, z, theta, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f), z, 'k', compute_ur(app, r, z, theta, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f), z, 'r', compute_uz(app, r, z, theta, ur_max, r_max, z_max, c_1, c_2, a, i_f), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end

            % Update field values to match sliders
            if app.AzimuthField_2.Value ~= app.AzimuthSlider_2.Value
                app.AzimuthField_2.Value = app.AzimuthSlider_2.Value;
            else
            end
            if app.MaxoutflowvelField_2.Value ~= app.MaxoutflowvelSlider_2.Value
                app.MaxoutflowvelField_2.Value = app.MaxoutflowvelSlider_2.Value;
            else
            end
            if app.MaxrField_2.Value ~= app.MaxrSlider_2.Value
                app.MaxrField_2.Value = app.MaxrSlider_2.Value;
            else
            end
            if app.MaxzField_2.Value ~= app.MaxzSlider_2.Value
                app.MaxzField_2.Value = app.MaxzSlider_2.Value;
            else
            end
            if app.utransField_2.Value ~= app.utransSlider_2.Value
                app.utransField_2.Value = app.utransSlider_2.Value;
            else
            end
            if app.IntensificationtimeField_2.Value ~= app.IntensificationtimeSlider_2.Value
                app.IntensificationtimeField_2.Value = app.IntensificationtimeSlider_2.Value;
            else
            end
            if app.TimeofstormField_2.Value ~= app.TimeofstormSlider_2.Value
                app.TimeofstormField_2.Value = app.TimeofstormSlider_2.Value;
            else
            end
            if app.ShapingfunctionvariableField_2.Value ~= app.ShapingfunctionvariableSlider_2.Value
                app.ShapingfunctionvariableField_2.Value = app.ShapingfunctionvariableSlider_2.Value;
            else
            end
            if app.rField.Value ~= app.rSlider.Value
                app.rField.Value = app.rSlider.Value;
            else
            end
            if app.rtField_2.Value ~= app.rtSlider_2.Value
                app.rtField_2.Value = app.rtSlider_2.Value;
            end

            % Update sliders if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.AzimuthField.Value = app.AzimuthField_2.Value;
                app.AzimuthSlider.Value = app.AzimuthSlider_2.Value;
                app.MaxoutflowvelField.Value = app.MaxoutflowvelField_2.Value;
                app.MaxoutflowvelSlider.Value = app.MaxoutflowvelSlider_2.Value;
                app.MaxrField.Value = app.MaxrField_2.Value; 
                app.MaxrSlider.Value = app.MaxrSlider_2.Value;
                app.MaxzField.Value = app.MaxzField_2.Value;
                app.MaxzSlider.Value = app.MaxzSlider_2.Value;
                app.utransField.Value = app.utransField_2.Value;
                app.utransSlider.Value = app.utransSlider_2.Value;
                app.IntensificationtimeField.Value = app.IntensificationtimeField_2.Value;
                app.IntensificationtimeSlider.Value = app.IntensificationtimeSlider_2.Value;
                app.TimeofstormField.Value = app.TimeofstormField_2.Value;
                app.TimeofstormSlider.Value = app.TimeofstormSlider_2.Value;
                app.ShapingfunctionvariableField.Value = app.ShapingfunctionvariableField_2.Value;
                app.ShapingfunctionvariableSlider.Value = app.ShapingfunctionvariableSlider_2.Value;
                app.rtField.Value = app.rtField_2.Value;
                app.rtSlider.Value = app.rtSlider_2.Value;

                t = app.TimeofstormSlider.Value;
                for t_int = app.IntensificationtimeSlider.Value
                if t <= t_int
                    i_f = t / t_int;
                else 
                  i_f = exp((-(t-t_int))/11.542);   
                end
                end
                theta = app.AzimuthSlider.Value;
                r = 0:0.1:10000;
                z = app.zSlider.Value;
                r_max = app.MaxrSlider.Value * 1000;
                z_max = app.MaxzSlider.Value;
                ur_max = app.MaxoutflowvelSlider.Value;
                u_trans = app.utransSlider.Value;
                r_t = app.rtSlider.Value;
                c_1 = -0.15;
                c_2 = -3.2175; 
                a = app.ShapingfunctionvariableSlider.Value;

                plot(app.HorizontalAxes, r, compute_umag(app, r, z, theta, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f), 'k', r, compute_ur(app, r, z, theta, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f), 'r', r, compute_uz(app, r, z, theta, ur_max, r_max, z_max, c_1, c_2, a, i_f), 'b');
                p = app.HorizontalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.HorizontalAxes, 'Location', 'southoutside');

                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end
        end

        % Value changed function: AzimuthField_2, 
        % IntensificationtimeField_2, MaxoutflowvelField_2, MaxrField_2, 
        % MaxzField_2, ShapingfunctionvariableField_2, TimeofstormField_2, 
        % rField, rtField_2, utransField_2
        function Field2ValueChanged(app, event)
            % Define parameters for vertical profile
            t = app.TimeofstormField_2.Value;
            for t_int = app.IntensificationtimeField_2.Value
            if t <= t_int
                i_f = t / t_int;
            else 
              i_f = exp((-(t-t_int))/11.542);   
            end
            end
            theta = app.AzimuthField_2.Value;
            r = app.rField.Value * 1000;
            z = 0:0.1:1000;
            r_max = app.MaxrField_2.Value * 1000;
            z_max = app.MaxzField_2.Value;
            ur_max = app.MaxoutflowvelField_2.Value;
            u_trans = app.utransField_2.Value;
            r_t = app.rtField_2.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableField_2.Value;

            % Plot vertical profile
            plot(app.VerticalAxes, compute_umag(app, r, z, theta, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f), z, 'k', compute_ur(app, r, z, theta, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f), z, 'r', compute_uz(app, r, z, theta, ur_max, r_max, z_max, c_1, c_2, a, i_f), z, 'b');
            p = app.VerticalAxes.Children;
            p(1).LineWidth = 1.5;
            p(2).LineWidth = 1.5;
            p(3).LineWidth = 1.5;
            legend(app.VerticalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(app.VerticalAxes, 'Location', 'southoutside');

            % "Plotting options" menu check box adjustments
            if app.PlotmagnitudeCheckBox.Value == 1
                p(3).Visible = 'on';
            else p(3).Visible = 'off';
            end
            if app.PlothorizontalvelocityCheckBox.Value == 1
                p(2).Visible = 'on';
            else p(2).Visible = 'off';
            end
            if app.PlotverticalvelocityCheckBox.Value == 1
                p(1).Visible = 'on';
            else p(1).Visible = 'off';
            end
            
            % Update sliders to match field values
            if app.AzimuthField_2.Value ~= app.AzimuthSlider_2.Value
                app.AzimuthField_2.Value = app.AzimuthSlider_2.Value;
            else
            end
            if app.MaxoutflowvelField_2.Value ~= app.MaxoutflowvelSlider_2.Value
                app.MaxoutflowvelSlider_2.Value = app.MaxoutflowvelField_2.Value;
            else
            end
            if app.MaxrField_2.Value ~= app.MaxrSlider_2.Value
                app.MaxrSlider_2.Value = app.MaxrField_2.Value;
            else
            end
            if app.MaxzField_2.Value ~= app.MaxzSlider_2.Value
                app.MaxzSlider_2.Value = app.MaxzField_2.Value;
            else
            end
            if app.utransField_2.Value ~= app.utransSlider_2.Value
                app.utransSlider_2.Value = app.utransField_2.Value;
            else
            end
            if app.IntensificationtimeField_2.Value ~= app.IntensificationtimeSlider_2.Value
                app.IntensificationtimeSlider_2.Value = app.IntensificationtimeField_2.Value;
            else
            end
            if app.TimeofstormField_2.Value ~= app.TimeofstormSlider_2.Value
                app.TimeofstormSlider_2.Value = app.TimeofstormField_2.Value;
            else
            end
            if app.ShapingfunctionvariableField_2.Value ~= app.ShapingfunctionvariableSlider_2.Value
                app.ShapingfunctionvariableSlider_2.Value = app.ShapingfunctionvariableField_2.Value;
            else
            end
            if app.rField.Value ~= app.rSlider.Value
                app.rSlider.Value = app.rField.Value;
            else
            end
            if app.rtField_2.Value ~= app.rtSlider_2.Value
                app.rtSlider_2.Value = app.rtField_2.Value;
            end

            % Update sliders if fixed parameters check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.AzimuthField.Value = app.AzimuthField_2.Value;
                app.AzimuthSlider.Value = app.AzimuthSlider_2.Value;
                app.MaxoutflowvelField.Value = app.MaxoutflowvelField_2.Value;
                app.MaxoutflowvelSlider.Value = app.MaxoutflowvelSlider_2.Value;
                app.MaxrField.Value = app.MaxrField_2.Value; 
                app.MaxrSlider.Value = app.MaxrSlider_2.Value;
                app.MaxzField.Value = app.MaxzField_2.Value;
                app.MaxzSlider.Value = app.MaxzSlider_2.Value;
                app.utransField.Value = app.utransField_2.Value;
                app.utransSlider.Value = app.utransSlider_2.Value;
                app.IntensificationtimeField.Value = app.IntensificationtimeField_2.Value;
                app.IntensificationtimeSlider.Value = app.IntensificationtimeSlider_2.Value;
                app.TimeofstormField.Value = app.TimeofstormField_2.Value;
                app.TimeofstormSlider.Value = app.TimeofstormSlider_2.Value;
                app.ShapingfunctionvariableField.Value = app.ShapingfunctionvariableField_2.Value;
                app.ShapingfunctionvariableSlider.Value = app.ShapingfunctionvariableSlider_2.Value;
                app.rtField.Value = app.rtField_2.Value;
                app.rtSlider.Value = app.rtSlider_2.Value;

                t = app.TimeofstormField.Value;
                for t_int = app.IntensificationtimeField.Value
                if t <= t_int
                    i_f = t / t_int;
                else 
                  i_f = exp((-(t-t_int))/11.542);   
                end
                end
                theta = app.AzimuthField.Value;
                r = 0:0.1:10000;
                z = app.zField.Value;
                r_max = app.MaxrField.Value * 1000;
                z_max = app.MaxzField.Value;
                ur_max = app.MaxoutflowvelField.Value;
                u_trans = app.utransField.Value;
                r_t = app.rtField.Value;
                c_1 = -0.15;
                c_2 = -3.2175; 
                a = app.ShapingfunctionvariableField.Value;

                plot(app.HorizontalAxes, r, compute_umag(app, r, z, theta, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f), 'k', r, compute_ur(app, r, z, theta, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f), 'r', r, compute_uz(app, r, z, theta, ur_max, r_max, z_max, c_1, c_2, a, i_f), 'b');
                p = app.HorizontalAxes.Children;
                p(1).LineWidth = 1.5;
                p(2).LineWidth = 1.5;
                p(3).LineWidth = 1.5;
                legend(app.HorizontalAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
                legend(app.HorizontalAxes, 'Location', 'southoutside');

                if app.PlotmagnitudeCheckBox.Value == 1
                    p(3).Visible = 'on';
                else p(3).Visible = 'off';
                end
                if app.PlothorizontalvelocityCheckBox.Value == 1
                    p(2).Visible = 'on';
                else p(2).Visible = 'off';
                end
                if app.PlotverticalvelocityCheckBox.Value == 1
                    p(1).Visible = 'on';
                else p(1).Visible = 'off';
                end
            else
            end
        end

        % Button pushed function: ExportPlotButton_2
        function ExportPlotButtonPushed(app, event)
            % Create a temporary figure of the radial plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.HorizontalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.HorizontalAxes.XLim;
            figAxes.YLim = app.HorizontalAxes.YLim;
            figAxes.ZLim = app.HorizontalAxes.ZLim;
            figAxes.DataAspectRatio = app.HorizontalAxes.DataAspectRatio;
            legend(figAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(figAxes, 'Location', 'southoutside');
            xlabel(figAxes, 'Distance from downdraft center [m]');
            ylabel(figAxes, 'Velocity [m/s]');
            title(figAxes, 'Radial Profile of Velocity');

            % Save plot as png file
            saveas(fig, 'OBV_Chay_radial_profile_plot', 'png');

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton_2
        function ExportDataTableButtonPushed(app, event)
            % Define parameters for radial profile
            t = app.TimeofstormSlider.Value;
            for t_int = app.IntensificationtimeSlider.Value
            if t <= t_int
                i_f = t / t_int;
            else 
              i_f = exp((-(t-t_int))/11.542);   
            end
            end
            theta = app.AzimuthSlider.Value;
            z = app.zSlider.Value;
            r_max = app.MaxrSlider.Value * 1000;
            z_max = app.MaxzSlider.Value;
            ur_max = app.MaxoutflowvelSlider.Value;
            u_trans = app.utransSlider.Value;
            r_t = app.rtSlider.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider.Value;

            % Put x axis values into an array
            r = [0.1:0.1:10000];
            % Compute velocities and insert into arrays
            m = [compute_umag(app, r, z, theta, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f)];
            h = [compute_ur(app, r, z, theta, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f)];
            v = [compute_uz(app, r, z, theta, ur_max, r_max, z_max, c_1, c_2, a, i_f)];

            % Arrange arrays into a table
            ar = [r; m; h; v];
            tbl = array2table(ar.');
            tbl = renamevars(tbl, ["Var1", "Var2", "Var3", "Var4"], ["Distance from downdraft center [m]","Magnitude [m/s]","Horizontal Velocity [m/s]","Vertical Velocity [m/s]"]);
            
            % Write table into csv file
            writetable(tbl, 'OBV_Chay_radial_profile_table.csv')

        end

        % Button pushed function: ExportPlotButton_3
        function ExportPlotButton_2Pushed(app, event)
            % Create a temporary figure of the vertical plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.VerticalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.VerticalAxes.XLim;
            figAxes.YLim = app.VerticalAxes.YLim;
            figAxes.ZLim = app.VerticalAxes.ZLim;
            figAxes.DataAspectRatio = app.VerticalAxes.DataAspectRatio;
            legend(figAxes, 'Magnitude', 'Horizontal Velocity', 'Vertical Velocity');
            legend(figAxes, 'Location', 'southoutside');
            xlabel(figAxes, 'Velocity [m/s]');
            ylabel(figAxes, 'Height above ground [m]');
            title(figAxes, 'Vertical Profile of Velocity');

            % Save as png file
            saveas(fig, 'OBV_Chay_vertical_profile_plot', 'png');

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton_3
        function ExportDataTableButton_2Pushed(app, event)
            % Define parameters for vertical profile
            t = app.TimeofstormSlider_2.Value;
            for t_int = app.IntensificationtimeSlider_2.Value
            if t <= t_int
                i_f = t / t_int;
            else 
              i_f = exp((-(t-t_int))/11.542);   
            end
            end
            theta = app.AzimuthSlider_2.Value;
            r = app.rSlider.Value * 1000;
            r_max = app.MaxrSlider_2.Value * 1000;
            z_max = app.MaxzSlider_2.Value;
            ur_max = app.MaxoutflowvelSlider_2.Value;
            u_trans = app.utransSlider_2.Value;
            r_t = app.rtSlider_2.Value;
            c_1 = -0.15;
            c_2 = -3.2175; 
            a = app.ShapingfunctionvariableSlider_2.Value;
            
            % Put y axis values into an array
            z = [0:0.1:1000];
            % Compute velocities and insert into arrays
            m = [compute_umag(app, r, z, theta, ur_max, u_trans, r_max, r_t, z_max, c_1, c_2, a, i_f)];
            h = [compute_ur(app, r, z, theta, ur_max, u_trans, r_t, z_max, c_1, c_2, a, i_f)];
            v = [compute_uz(app, r, z, theta, ur_max, r_max, z_max, c_1, c_2, a, i_f)];

            % Arrange arrays into a table
            ar = [m; h; v; z];
            tbl = array2table(ar.');
            tbl = renamevars(tbl, ["Var1", "Var2", "Var3", "Var4"], ["Magnitude [m/s]","Horizontal Velocity [m/s]","Vertical Velocity [m/s]","Height above ground [m]"]);

            % Write table into csv file
            writetable(tbl, 'OBV_Chay_vertical_profile_table.csv')
        end

        % Close request function: UIFigure
        function OBVChayCloseRequest(app, event)
            % Reenable OBV: Chay button
            app.CallingApp.ChayAlbermaniWilson2006Button.Enable = 'on';

            % Close OBV: Chay window
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @OBVChayCloseRequest, true);
            app.HorizontalAxes = uiaxes(app.UIFigure);
            title(app.HorizontalAxes, 'Radial Profile of Velocity')
            xlabel(app.HorizontalAxes, 'Distance from downdraft center [m]')
            ylabel(app.HorizontalAxes, 'Velocity [m/s]')
            zlabel(app.HorizontalAxes, 'Z')
            app.VerticalAxes = uiaxes(app.UIFigure);
            title(app.VerticalAxes, 'Vertical Profile of Velocity')
            xlabel(app.VerticalAxes, 'Velocity [m/s]')
            ylabel(app.VerticalAxes, 'Height above ground [m]')
            zlabel(app.VerticalAxes, 'Z')
            app.ChayAlbermaniWilson2006Label = uilabel(app.UIFigure);
            app.ChayAlbermaniWilson2006Label.Text = 'Chay, Albermani, & Wilson (2006)';
            app.ModelParametersPanel = uipanel(app.UIFigure);
            app.ModelParametersPanel.Title = 'Model Parameters';
            app.HeightofmaximumoutflowvelocitymLabel = uilabel(app.ModelParametersPanel);
            app.HeightofmaximumoutflowvelocitymLabel.Text = 'Height of maximum outflow velocity [m]';
            app.MaxzSlider = uislider(app.ModelParametersPanel);
            app.MaxzSlider.Limits = [0 500];
            app.MaxzSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxzSlider.Value = 100;
            app.MaximumoutflowvelocitymsLabel = uilabel(app.ModelParametersPanel);
            app.MaximumoutflowvelocitymsLabel.Text = 'Maximum outflow velocity [m/s]';
            app.MaxoutflowvelSlider = uislider(app.ModelParametersPanel);
            app.MaxoutflowvelSlider.Limits = [0 60];
            app.MaxoutflowvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxoutflowvelSlider.Value = 40;
            app.MaxoutflowvelField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.MaxoutflowvelField.Limits = [0 60];
            app.MaxoutflowvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxoutflowvelField.Value = 40;
            app.MaxzField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.MaxzField.Limits = [0 500];
            app.MaxzField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxzField.Value = 100;
            app.MaxrField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.MaxrField.Limits = [0 3];
            app.MaxrField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxrField.Value = 1;
            app.RadiusofmaximumoutflowvelocitykmLabel = uilabel(app.ModelParametersPanel);
            app.RadiusofmaximumoutflowvelocitykmLabel.Text = 'Radius of maximum outflow velocity [km]';
            app.MaxrSlider = uislider(app.ModelParametersPanel);
            app.MaxrSlider.Limits = [0 3];
            app.MaxrSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxrSlider.Value = 1;
            app.ShapingfunctionvariableSliderLabel = uilabel(app.ModelParametersPanel);
            app.ShapingfunctionvariableSliderLabel.Text = 'Shaping function variable';
            app.ShapingfunctionvariableSlider = uislider(app.ModelParametersPanel);
            app.ShapingfunctionvariableSlider.Limits = [1 4];
            app.ShapingfunctionvariableSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ShapingfunctionvariableSlider.Value = 2;
            app.ShapingfunctionvariableField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.ShapingfunctionvariableField.Limits = [1 4];
            app.ShapingfunctionvariableField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.ShapingfunctionvariableField.Value = 2;
            app.LengthofstormintensificationminSliderLabel = uilabel(app.ModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel.Text = 'Time length of storm intensification [min]';
            app.IntensificationtimeSlider = uislider(app.ModelParametersPanel);
            app.IntensificationtimeSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.IntensificationtimeSlider.Value = 10;
            app.IntensificationtimeField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.IntensificationtimeField.Limits = [0 100];
            app.IntensificationtimeField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.IntensificationtimeField.Value = 10;
            app.TimeofstormSliderLabel = uilabel(app.ModelParametersPanel);
            app.TimeofstormSliderLabel.Text = 'Current time of storm [min]';
            app.TimeofstormField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.TimeofstormField.Limits = [0 100];
            app.TimeofstormField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.TimeofstormField.Value = 30;
            app.TimeofstormSlider = uislider(app.ModelParametersPanel);
            app.TimeofstormSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.TimeofstormSlider.Value = 30;
            app.TranslationalvelocityofstormmsLabel = uilabel(app.ModelParametersPanel);
            app.TranslationalvelocityofstormmsLabel.Text = 'Translational velocity of storm [m/s]';
            app.utransSlider = uislider(app.ModelParametersPanel);
            app.utransSlider.Limits = [0 30];
            app.utransSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.utransSlider.Value = 10;
            app.utransField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.utransField.Limits = [0 30];
            app.utransField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.utransField.Value = 10;
            app.AzimuthpositionintheoutflowdegreeLabel = uilabel(app.ModelParametersPanel);
            app.AzimuthpositionintheoutflowdegreeLabel.Text = 'Azimuth position in the outflow [degree]';
            app.AzimuthSlider = uislider(app.ModelParametersPanel);
            app.AzimuthSlider.Limits = [0 180];
            app.AzimuthSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.AzimuthSlider.Value = 45;
            app.AzimuthField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.AzimuthField.Limits = [0 180];
            app.AzimuthField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.AzimuthField.Value = 45;
            app.TimedependentradiusofmaximumoutflowvelocitykmLabel = uilabel(app.ModelParametersPanel);
            app.TimedependentradiusofmaximumoutflowvelocitykmLabel.Text = {'Time dependent radius of maximum '; 'outflow velocity [km]'; ''};
            app.rtSlider = uislider(app.ModelParametersPanel);
            app.rtSlider.Limits = [0 3];
            app.rtSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.rtSlider.Value = 1;
            app.rtField = uieditfield(app.ModelParametersPanel, 'numeric');
            app.rtField.Limits = [0 3];
            app.rtField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.rtField.Value = 1;
            app.Panel = uipanel(app.UIFigure);
            app.zmLabel = uilabel(app.Panel);
            app.zmLabel.Text = 'Height above ground [m]';
            app.zSlider = uislider(app.Panel);
            app.zSlider.Limits = [0 1000];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.zSlider.Value = 100;
            app.zField = uieditfield(app.Panel, 'numeric');
            app.zField.Limits = [0 1000];
            app.zField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.zField.Value = 100;
            app.PlottingOptionsPanel = uipanel(app.UIFigure);
            app.PlottingOptionsPanel.Title = 'Plotting Options';
            app.PlotmagnitudeCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotmagnitudeCheckBox.Text = 'Plot magnitude';
            app.PlothorizontalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlothorizontalvelocityCheckBox.Text = 'Plot horizontal velocity';
            app.PlotverticalvelocityCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.PlotverticalvelocityCheckBox.Text = 'Plot vertical velocity';
            app.ExportPlotButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton_2.Text = {'Export Plot'; ''};
            app.ExportDataTableButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButtonPushed, true);
            app.ExportDataTableButton_2.Text = {'Export Data Table'; ''};
            app.ExportPlotButton_3 = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButton_2Pushed, true);
            app.ExportPlotButton_3.Text = {'Export Plot'; ''};
            app.ExportDataTableButton_3 = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButton_2Pushed, true);
            app.ExportDataTableButton_3.Text = {'Export Data Table'; ''};
            app.VerticalProfileModelParametersPanel = uipanel(app.UIFigure);
            app.VerticalProfileModelParametersPanel.Title = 'Vertical Profile: Model Parameters';
            app.HeightofmaximumoutflowvelocitymLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.HeightofmaximumoutflowvelocitymLabel_2.Text = 'Height of maximum outflow velocity [m]';
            app.MaxzSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.MaxzSlider_2.Limits = [0 500];
            app.MaxzSlider_2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.MaxzSlider_2.Value = 100;
            app.MaxzField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.MaxzField_2.Limits = [0 500];
            app.MaxzField_2.ValueChangedFcn = createCallbackFcn(app, @Field2ValueChanged, true);
            app.MaxzField_2.Value = 100;
            app.MaximumoutflowvelocitymsLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.MaximumoutflowvelocitymsLabel_2.Text = 'Maximum outflow velocity [m/s]';
            app.MaxoutflowvelSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.MaxoutflowvelSlider_2.Limits = [0 60];
            app.MaxoutflowvelSlider_2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.MaxoutflowvelSlider_2.Value = 40;
            app.MaxoutflowvelField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.MaxoutflowvelField_2.Limits = [0 60];
            app.MaxoutflowvelField_2.ValueChangedFcn = createCallbackFcn(app, @Field2ValueChanged, true);
            app.MaxoutflowvelField_2.Value = 40;
            app.MaxrField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.MaxrField_2.Limits = [0 3];
            app.MaxrField_2.ValueChangedFcn = createCallbackFcn(app, @Field2ValueChanged, true);
            app.MaxrField_2.Value = 1;
            app.RadiusofmaximumoutflowvelocitykmLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.RadiusofmaximumoutflowvelocitykmLabel_2.Text = 'Radius of maximum outflow velocity [km]';
            app.MaxrSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.MaxrSlider_2.Limits = [0 3];
            app.MaxrSlider_2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.MaxrSlider_2.Value = 1;
            app.ShapingfunctionvariableSliderLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.ShapingfunctionvariableSliderLabel_2.Text = 'Shaping function variable';
            app.ShapingfunctionvariableSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.ShapingfunctionvariableSlider_2.Limits = [1 4];
            app.ShapingfunctionvariableSlider_2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.ShapingfunctionvariableSlider_2.Value = 2;
            app.ShapingfunctionvariableField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.ShapingfunctionvariableField_2.Limits = [1 4];
            app.ShapingfunctionvariableField_2.ValueChangedFcn = createCallbackFcn(app, @Field2ValueChanged, true);
            app.ShapingfunctionvariableField_2.Value = 2;
            app.LengthofstormintensificationminSliderLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.LengthofstormintensificationminSliderLabel_2.Text = 'Time length of storm intensification [min]';
            app.IntensificationtimeSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.IntensificationtimeSlider_2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.IntensificationtimeSlider_2.Value = 10;
            app.IntensificationtimeField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.IntensificationtimeField_2.Limits = [0 100];
            app.IntensificationtimeField_2.ValueChangedFcn = createCallbackFcn(app, @Field2ValueChanged, true);
            app.IntensificationtimeField_2.Value = 10;
            app.TimeofstormSliderLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.TimeofstormSliderLabel_2.Text = 'Current time of storm [min]';
            app.TimeofstormField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.TimeofstormField_2.Limits = [0 100];
            app.TimeofstormField_2.ValueChangedFcn = createCallbackFcn(app, @Field2ValueChanged, true);
            app.TimeofstormField_2.Value = 30;
            app.TimeofstormSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.TimeofstormSlider_2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.TimeofstormSlider_2.Value = 30;
            app.TranslationalvelocityofstormmsLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.TranslationalvelocityofstormmsLabel_2.Text = 'Translational velocity of storm [m/s]';
            app.utransSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.utransSlider_2.Limits = [0 30];
            app.utransSlider_2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.utransSlider_2.Value = 10;
            app.utransField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.utransField_2.Limits = [0 30];
            app.utransField_2.ValueChangedFcn = createCallbackFcn(app, @Field2ValueChanged, true);
            app.utransField_2.Value = 10;
            app.AzimuthpositionintheoutflowdegreeLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.AzimuthpositionintheoutflowdegreeLabel_2.Text = 'Azimuth position in the outflow [degree]';
            app.AzimuthSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.AzimuthSlider_2.Limits = [0 180];
            app.AzimuthSlider_2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.AzimuthSlider_2.Value = 45;
            app.AzimuthField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.AzimuthField_2.Limits = [0 180];
            app.AzimuthField_2.ValueChangedFcn = createCallbackFcn(app, @Field2ValueChanged, true);
            app.AzimuthField_2.Value = 45;
            app.TimedependentradiusofmaximumoutflowvelocitykmLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.TimedependentradiusofmaximumoutflowvelocitykmLabel_2.Text = {'Time dependent radius of maximum '; 'outflow velocity [km]'; ''};
            app.rtSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.rtSlider_2.Limits = [0 3];
            app.rtSlider_2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.rtSlider_2.Value = 1;
            app.rtField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.rtField_2.Limits = [0 3];
            app.rtField_2.ValueChangedFcn = createCallbackFcn(app, @Field2ValueChanged, true);
            app.rtField_2.Value = 1;
            app.Panel_2 = uipanel(app.UIFigure);
            app.DistancefromdowndraftcenterkmLabel = uilabel(app.Panel_2);
            app.DistancefromdowndraftcenterkmLabel.Text = 'Distance from downdraft center [km]';
            app.rSlider = uislider(app.Panel_2);
            app.rSlider.Limits = [0 3];
            app.rSlider.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.rSlider.Value = 1;
            app.rField = uieditfield(app.Panel_2, 'numeric');
            app.rField.Limits = [0 3];
            app.rField.ValueChangedFcn = createCallbackFcn(app, @Field2ValueChanged, true);
            app.rField.Value = 1;
            app.Panel_3 = uipanel(app.UIFigure);
            app.FixedParametersCheckBox = uicheckbox(app.Panel_3);
            app.FixedParametersCheckBox.Text = 'Keep model parameters fixed';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OBV_Chay(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end