classdef OBV_Li < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Hyperlink                 matlab.ui.control.Hyperlink
        Panel                     matlab.ui.container.Panel
        zField                    matlab.ui.control.NumericEditField
        zSlider                   matlab.ui.control.Slider
        zmLabel                   matlab.ui.control.Label
        PlottingOptionsPanel      matlab.ui.container.Panel
        FixedParametersCheckBox   matlab.ui.control.CheckBox
        Panel_2                   matlab.ui.container.Panel
        rSlider                   matlab.ui.control.Slider
        rField                    matlab.ui.control.NumericEditField
        zmLabel_2                 matlab.ui.control.Label
        ExportDataTableButton_2   matlab.ui.control.Button
        ExportPlotButton_2        matlab.ui.control.Button
        VerticalProfileModelParametersPanel  matlab.ui.container.Panel
        MaxhorizontalvelField_2   matlab.ui.control.NumericEditField
        JetDiameterSlider_2       matlab.ui.control.Slider
        JetDiameterkmLabel_2      matlab.ui.control.Label
        JetDiameterField_2        matlab.ui.control.NumericEditField
        MaxhorizontalvelSlider_2  matlab.ui.control.Slider
        MaximumhorizontalvelocitymsLabel_2  matlab.ui.control.Label
        ExportDataTableButton     matlab.ui.control.Button
        ExportPlotButton          matlab.ui.control.Button
        RadialProfileModelParametersPanel  matlab.ui.container.Panel
        JetDiameterSlider         matlab.ui.control.Slider
        JetDiameterkmLabel        matlab.ui.control.Label
        JetDiameterField          matlab.ui.control.NumericEditField
        MaxhorizontalvelField     matlab.ui.control.NumericEditField
        MaxhorizontalvelSlider    matlab.ui.control.Slider
        MaximumhorizontalvelocitymsLabel  matlab.ui.control.Label
        Lietal2012Label           matlab.ui.control.Label
        VerticalAxes              matlab.ui.control.UIAxes
        HorizontalAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Selection window
    end
    
    methods (Access = private)
        
        function u_rr = compute_u_rr(app, u_mm, u_mVS, u_RS)
            % Horizontal downburst velocity
            u_rr = u_mm .* u_mVS .* u_RS;
        end
        
        function u_rz = compute_urz(app, u_mm, u_mRS, u_VS)
            % Vertical downburst velocity
            u_rz = u_mm .* u_mRS .* u_VS;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller5)
            % Store selection menu app object
            app.CallingApp = caller5;

            % Set up plotting options menu
            app.FixedParametersCheckBox.Value = 1;
            
            % Define parameters for radial profile
            r = 0:0.1:10000;
            u_mm = app.MaxhorizontalvelField.Value;
            D = app.JetDiameterField.Value * 1000;          
            z = app.zField.Value;
            r_dm = 1.473*D; % Radius where half-height is smallest.
            eta_r = 1.134;
            xi_r = 0.28;
            delt = D * (eta_r - (r/r_dm) .* exp ( (1 - (r/r_dm).^(2*xi_r))./(2*xi_r)) );                      
            r_mm = 0.0078*D;
            eta_z = 2.01;
            xi_z = -0.0363;
            r_m = D * (eta_z - (z/r_mm) .* exp ( (1 - (z/r_mm).^(2*xi_z))./(2*xi_z) ) );            
            gm = 0.159;
            z_um = 0.0393*D;
            u_mVS = (z/z_um).^gm .* exp(gm*(1-z/z_um));                        
            R_c = 0.599*r_m; 
            bet = 1.287;
            u_RS = zeros(1, length(r));
            for i = 1:length(r)
                if r(i) <= r_m
                    u_RS(i) = r(i)/r_m;
                else
                    u_RS(i) = exp(-((r(i)-r_m)/R_c)^bet);
                end
            end

            % Plot radial profile horizontal and vertical velocities as well as magnitude
            plot(app.HorizontalAxes, r, compute_u_rr(app, u_mm, u_mVS, u_RS), 'k');
            p = app.HorizontalAxes.Children;
            p.LineWidth = 1.5;            

            % Adjust axis for radial profile
            r_lim = 2 * D;
            xlim(app.HorizontalAxes, [0, (r_lim+2000)]);

            % Define parameters for vertical profile
            z = 0:0.1:1000;
            u_mm = app.MaxhorizontalvelField_2.Value;
            D = app.JetDiameterField_2.Value * 1000;
            r = app.rField.Value * 1000;            
            r_dm = 1.473*D;
            eta_r = 1.134;
            xi_r = 0.28;
            delt = D * (eta_r - (r/r_dm) .* exp ( (1 - (r/r_dm).^(2*xi_r))./(2*xi_r)) );                      
            r_um = 1.007*D;
            u_mRS = exp(2.242*(1 - (r_um./r) - log(r./r_um) ) );
            gama = 0.261;
            z_m = delt *  exp((1/gama) * (gama + gama*lambertw(-1, -exp( -(gama+log(2))/gama)) + log(2)  ));                                   
            u_VS = (z./z_m).^gama .* exp(gama*(1-z./z_m));

            % Plot vertical profile horizontal and vertical velocities as well as magnitude
            plot(app.VerticalAxes, compute_urz(app, u_mm, u_mRS, u_VS), z, 'k');
            p = app.VerticalAxes.Children;
            p.LineWidth = 1.5;
        end

        % Value changed function: JetDiameterField, MaxhorizontalvelField, 
        % zField
        function FieldValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:0.1:10000;
            rN = length(r);
            u_mm = app.MaxhorizontalvelField.Value;
            D = app.JetDiameterField.Value * 1000;          
            z = app.zField.Value;
            r_dm = 1.473*D; % Radius where half-height is smallest.
            eta_r = 1.134;
            xi_r = 0.28;
            delt = D * (eta_r - (r/r_dm) .* exp ( (1 - (r/r_dm).^(2*xi_r))./(2*xi_r)) );                      
            r_mm = 0.0078*D;
            eta_z = 2.01;
            xi_z = -0.0363;
            r_m = D * (eta_z - (z/r_mm) .* exp ( (1 - (z/r_mm).^(2*xi_z))./(2*xi_z) ) );            
            gm = 0.159;
            z_um = 0.0393*D;
            u_mVS = (z/z_um).^gm .* exp(gm*(1-z/z_um));                        
            R_c = 0.599*r_m; 
            bet = 1.287;
            u_RS = nan(1,rN);
            for i = 1:rN
                if r(i) <= r_m
                    u_RS(i) = r(i)/r_m;
                else
                    u_RS(i) = exp(- ( (r(i)-r_m) /R_c)^bet );
                end
            end

            % Plot radial profile
            plot(app.HorizontalAxes, r, compute_u_rr(app, u_mm, u_mVS, u_RS), 'k');
            p = app.HorizontalAxes.Children;
            p.LineWidth = 1.5;  

            % Adjust axis for radial profile
            r_lim = 2 * D;
            xlim(app.HorizontalAxes, [0, (r_lim+2000)]);

            % Update sliders to match field values
            if app.MaxhorizontalvelField.Value ~= app.MaxhorizontalvelSlider.Value
                app.MaxhorizontalvelSlider.Value = app.MaxhorizontalvelField.Value;
            else 
            end
            if app.JetDiameterField.Value ~= app.JetDiameterSlider.Value
                app.JetDiameterSlider.Value = app.JetDiameterField.Value;
            else
            end
            if app.zField.Value ~= app.zSlider.Value
                app.zSlider.Value = app.zField.Value;
            else 
            end

            % Update field value if check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.JetDiameterField_2.Value = app.JetDiameterField.Value;
                app.JetDiameterSlider_2.Value = app.JetDiameterSlider.Value;
                app.MaxhorizontalvelField_2.Value = app.MaxhorizontalvelField.Value;
                app.MaxhorizontalvelSlider_2.Value = app.MaxhorizontalvelSlider.Value;

                % Define parameters for vertical profile
                z = 0:0.1:1000;
                u_mm = app.MaxhorizontalvelField_2.Value;
                D = app.JetDiameterField_2.Value * 1000;
                r = app.rField.Value * 1000;            
                r_dm = 1.473*D;
                eta_r = 1.134;
                xi_r = 0.28;
                delt = D * (eta_r - (r/r_dm) .* exp ( (1 - (r/r_dm).^(2*xi_r))./(2*xi_r)) );                      
                r_um = 1.007*D;
                u_mRS = exp(2.242*(1 - (r_um./r) - log(r./r_um) ) );
                gama = 0.261;
                z_m = delt *  exp((1/gama) * (gama + gama*lambertw(-1, -exp( -(gama+log(2))/gama)) + log(2)  ));                                   
                u_VS = (z./z_m).^gama .* exp(gama*(1-z./z_m));
    
                % Plot vertical profile
                plot(app.VerticalAxes, compute_urz(app, u_mm, u_mRS, u_VS), z, 'k');
                p = app.VerticalAxes.Children;
                p.LineWidth = 1.5;
            else
            end
        end

        % Value changed function: JetDiameterSlider, 
        % MaxhorizontalvelSlider, zSlider
        function SliderValueChanged(app, event)
            % Define parameters for radial profile
            r = 0:0.1:10000;
            rN = length(r);
            u_mm = app.MaxhorizontalvelSlider.Value;
            D = app.JetDiameterSlider.Value * 1000;          
            z = app.zSlider.Value;
            r_dm = 1.473*D; % Radius where half-height is smallest.
            eta_r = 1.134;
            xi_r = 0.28;
            delt = D * (eta_r - (r/r_dm) .* exp ( (1 - (r/r_dm).^(2*xi_r))./(2*xi_r)) );                      
            r_mm = 0.0078*D;
            eta_z = 2.01;
            xi_z = -0.0363;
            r_m = D * (eta_z - (z/r_mm) .* exp ( (1 - (z/r_mm).^(2*xi_z))./(2*xi_z) ) );            
            gm = 0.159;
            z_um = 0.0393*D;
            u_mVS = (z/z_um).^gm .* exp(gm*(1-z/z_um));                        
            R_c = 0.599*r_m; 
            bet = 1.287;
            u_RS = nan(1,rN);
            for i = 1:rN
                if r(i) <= r_m
                    u_RS(i) = r(i)/r_m;
                else
                    u_RS(i) = exp(- ( (r(i)-r_m) /R_c)^bet );
                end
            end

            % Plot radial profile 
            plot(app.HorizontalAxes, r, compute_u_rr(app, u_mm, u_mVS, u_RS), 'k');
            p = app.HorizontalAxes.Children;
            p.LineWidth = 1.5;
            
            % Adjust axis for radial profile
            r_lim = 2 * D;
            xlim(app.HorizontalAxes, [0, (r_lim+2000)]);

            % Update field values to match sliders
            if app.MaxhorizontalvelSlider.Value ~= app.MaxhorizontalvelField.Value
                app.MaxhorizontalvelField.Value = app.MaxhorizontalvelSlider.Value;
            else
            end  
            if app.JetDiameterSlider.Value ~= app.JetDiameterField.Value
                app.JetDiameterField.Value = app.JetDiameterSlider.Value;            
            else
            end
            if app.zSlider.Value ~= app.zField.Value
                app.zField.Value = app.zSlider.Value;
            else
            end

            % Update field value if check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.JetDiameterSlider_2.Value = app.JetDiameterSlider.Value;
                app.JetDiameterField_2.Value = app.JetDiameterField.Value;
                app.MaxhorizontalvelSlider_2.Value = app.MaxhorizontalvelSlider.Value;
                app.MaxhorizontalvelField_2.Value = app.MaxhorizontalvelField.Value;

                % Define parameters for vertical profile
                z = 0:0.1:1000;
                u_mm = app.MaxhorizontalvelField_2.Value;
                D = app.JetDiameterField_2.Value * 1000;
                r = app.rField.Value * 1000;            
                r_dm = 1.473*D;
                eta_r = 1.134;
                xi_r = 0.28;
                delt = D * (eta_r - (r/r_dm) .* exp ( (1 - (r/r_dm).^(2*xi_r))./(2*xi_r)) );                      
                r_um = 1.007*D;
                u_mRS = exp(2.242*(1 - (r_um./r) - log(r./r_um) ) );
                gama = 0.261;
                z_m = delt *  exp((1/gama) * (gama + gama*lambertw(-1, -exp( -(gama+log(2))/gama)) + log(2)  ));                                   
                u_VS = (z./z_m).^gama .* exp(gama*(1-z./z_m));

                % Plot vertical profile
                plot(app.VerticalAxes, compute_urz(app, u_mm, u_mRS, u_VS), z, 'k');
                p = app.VerticalAxes.Children;
                p.LineWidth = 1.5;
            else
            end
        end

        % Value changed function: JetDiameterField_2, 
        % MaxhorizontalvelField_2, rField
        function FieldValue2Changed(app, event)
            % Define parameters for vertical profile
            z = 0:0.1:1000;
            u_mm = app.MaxhorizontalvelField_2.Value;
            D = app.JetDiameterField_2.Value * 1000;
            r = app.rField.Value * 1000;            
            r_dm = 1.473*D;
            eta_r = 1.134;
            xi_r = 0.28;
            delt = D * (eta_r - (r/r_dm) .* exp ( (1 - (r/r_dm).^(2*xi_r))./(2*xi_r)) );                      
            r_um = 1.007*D;
            u_mRS = exp(2.242*(1 - (r_um./r) - log(r./r_um) ) );
            gama = 0.261;
            z_m = delt *  exp((1/gama) * (gama + gama*lambertw(-1, -exp( -(gama+log(2))/gama)) + log(2)  ));                                   
            u_VS = (z./z_m).^gama .* exp(gama*(1-z./z_m));

            % Plot vertical profile 
            plot(app.VerticalAxes, compute_urz(app, u_mm, u_mRS, u_VS), z, 'k');
            p = app.VerticalAxes.Children;
            p.LineWidth = 1.5;

            % Update field values to match sliders
            if app.MaxhorizontalvelField_2.Value ~= app.MaxhorizontalvelSlider_2.Value
                app.MaxhorizontalvelSlider_2.Value = app.MaxhorizontalvelField_2.Value;
            else
            end
            if app.JetDiameterField_2.Value ~= app.JetDiameterSlider_2.Value
                app.JetDiameterSlider_2.Value = app.JetDiameterField_2.Value;            
            else
            end
            if app.rField.Value ~= app.rSlider.Value
                app.rSlider.Value = app.rField.Value;
            else
            end
            
            % Update field value if check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.JetDiameterField.Value = app.JetDiameterField_2.Value;
                app.JetDiameterSlider.Value = app.JetDiameterSlider_2.Value;
                app.MaxhorizontalvelField.Value = app.MaxhorizontalvelField_2.Value;
                app.MaxhorizontalvelSlider.Value = app.MaxhorizontalvelSlider_2.Value;

                % Define radial profile parameters
                r = 0:0.1:10000;
                rN = length(r);
                u_mm = app.MaxhorizontalvelSlider.Value;
                D = app.JetDiameterSlider.Value * 1000;          
                z = app.zSlider.Value;
                r_dm = 1.473*D; % Radius where half-height is smallest.
                eta_r = 1.134;
                xi_r = 0.28;
                delt = D * (eta_r - (r/r_dm) .* exp ( (1 - (r/r_dm).^(2*xi_r))./(2*xi_r)) );                      
                r_mm = 0.0078*D;
                eta_z = 2.01;
                xi_z = -0.0363;
                r_m = D * (eta_z - (z/r_mm) .* exp ( (1 - (z/r_mm).^(2*xi_z))./(2*xi_z) ) );            
                gm = 0.159;
                z_um = 0.0393*D;
                u_mVS = (z/z_um).^gm .* exp(gm*(1-z/z_um));                        
                R_c = 0.599*r_m; 
                bet = 1.287;
                u_RS = nan(1,rN);
                for i = 1:rN
                    if r(i) <= r_m
                        u_RS(i) = r(i)/r_m;
                    else
                        u_RS(i) = exp(- ( (r(i)-r_m) /R_c)^bet );
                    end
                end

                % Plot radial profile
                plot(app.HorizontalAxes, r, compute_u_rr(app, u_mm, u_mVS, u_RS), 'k');
                p = app.HorizontalAxes.Children;
                p.LineWidth = 1.5;
            else
            end
        end

        % Value changed function: JetDiameterSlider_2, 
        % MaxhorizontalvelSlider_2, rSlider
        function SliderValue2Changed(app, event)
            % Define parameters for vertical profile
            z = 0:0.1:1000;
            u_mm = app.MaxhorizontalvelSlider_2.Value;
            D = app.JetDiameterSlider_2.Value * 1000;
            r = app.rSlider.Value * 1000;            
            r_dm = 1.473*D;
            eta_r = 1.134;
            xi_r = 0.28;
            delt = D * (eta_r - (r/r_dm) .* exp ( (1 - (r/r_dm).^(2*xi_r))./(2*xi_r)) );                      
            r_um = 1.007*D;
            u_mRS = exp(2.242*(1 - (r_um./r) - log(r./r_um) ) );
            gama = 0.261;
            z_m = delt *  exp((1/gama) * (gama + gama*lambertw(-1, -exp( -(gama+log(2))/gama)) + log(2)  ));                                   
            u_VS = (z./z_m).^gama .* exp(gama*(1-z./z_m));

            % Plot vertical profile
            plot(app.VerticalAxes, compute_urz(app, u_mm, u_mRS, u_VS), z, 'k');
            p = app.VerticalAxes.Children;
            p.LineWidth = 1.5;

            % Update field values to match sliders
            if app.MaxhorizontalvelSlider_2.Value ~= app.MaxhorizontalvelField_2.Value
                app.MaxhorizontalvelField_2.Value = app.MaxhorizontalvelSlider_2.Value;
            else
            end  
            if app.JetDiameterSlider_2.Value ~= app.JetDiameterField_2.Value
                app.JetDiameterField_2.Value = app.JetDiameterSlider_2.Value;
            else
            end
            if app.rSlider.Value ~= app.rField.Value
                app.rField.Value = app.rSlider.Value;
            else
            end

            % Update field value if check box is checked
            if app.FixedParametersCheckBox.Value == 1
                app.MaxhorizontalvelSlider.Value = app.MaxhorizontalvelSlider_2.Value;
                app.MaxhorizontalvelField.Value = app.MaxhorizontalvelField_2.Value;
                app.JetDiameterSlider.Value = app.JetDiameterSlider_2.Value;
                app.JetDiameterField.Value = app.JetDiameterField_2.Value;

                % Define radial profile parameters
                r = 0:0.1:10000;
                rN = length(r);
                u_mm = app.MaxhorizontalvelSlider.Value;
                D = app.JetDiameterSlider.Value * 1000;          
                z = app.zSlider.Value;
                r_dm = 1.473*D; % Radius where half-height is smallest.
                eta_r = 1.134;
                xi_r = 0.28;
                delt = D * (eta_r - (r/r_dm) .* exp ( (1 - (r/r_dm).^(2*xi_r))./(2*xi_r)) );                      
                r_mm = 0.0078*D;
                eta_z = 2.01;
                xi_z = -0.0363;
                r_m = D * (eta_z - (z/r_mm) .* exp ( (1 - (z/r_mm).^(2*xi_z))./(2*xi_z) ) );            
                gm = 0.159;
                z_um = 0.0393*D;
                u_mVS = (z/z_um).^gm .* exp(gm*(1-z/z_um));                        
                R_c = 0.599*r_m; 
                bet = 1.287;
                u_RS = nan(1,rN);
                for i = 1:rN
                    if r(i) <= r_m
                        u_RS(i) = r(i)/r_m;
                    else
                        u_RS(i) = exp(- ( (r(i)-r_m) /R_c)^bet );
                    end
                end

                % Plot radial profile
                plot(app.HorizontalAxes, r, compute_u_rr(app, u_mm, u_mVS, u_RS), 'k');
                p = app.HorizontalAxes.Children;
                p.LineWidth = 1.5;
            else
            end
        end

        % Button pushed function: ExportPlotButton
        function ExportPlotButtonPushed(app, event)
            % Create a temporary figure of the plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.HorizontalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.HorizontalAxes.XLim;
            figAxes.YLim = app.HorizontalAxes.YLim;
            figAxes.ZLim = app.HorizontalAxes.ZLim;
            figAxes.DataAspectRatio = app.HorizontalAxes.DataAspectRatio;
            xlabel(figAxes, 'Distance from downdraft center [m]');
            ylabel(figAxes, 'Velocity [m/s]');
            title(figAxes, 'Radial Profile of Velocity');

            % Save plot as png file
            saveas(fig, 'OBV_Li_radial_profile_plot', 'png');

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton
        function ExportDataTableButtonPushed(app, event)
            % Define parameters for radial profile
            r = 0:0.1:10000;
            rN = length(r);
            u_mm = app.MaxhorizontalvelSlider.Value;
            D = app.JetDiameterSlider.Value * 1000;          
            z = app.zSlider.Value;
            r_dm = 1.473*D; % Radius where half-height is smallest.
            eta_r = 1.134;
            xi_r = 0.28;
            delt = D * (eta_r - (r/r_dm) .* exp ( (1 - (r/r_dm).^(2*xi_r))./(2*xi_r)) );                      
            r_mm = 0.0078*D;
            eta_z = 2.01;
            xi_z = -0.0363;
            r_m = D * (eta_z - (z/r_mm) .* exp ( (1 - (z/r_mm).^(2*xi_z))./(2*xi_z) ) );            
            gm = 0.159;
            z_um = 0.0393*D;
            u_mVS = (z/z_um).^gm .* exp(gm*(1-z/z_um));                        
            R_c = 0.599*r_m; 
            bet = 1.287;
            u_RS = nan(1,rN);
            for i = 1:rN
                if r(i) <= r_m
                    u_RS(i) = r(i)/r_m;
                else
                    u_RS(i) = exp(- ( (r(i)-r_m) /R_c)^bet );
                end
            end

            % Put x axis values into an array
            r = [0:0.1:10000];
            % Compute velocities and insert into arrays
            m = [compute_u_rr(app, u_mm, u_mVS, u_RS)];

            % Arrange arrays into a table
            ar = [r; m];
            t = array2table(ar.');
            t = renamevars(t, ["Var1", "Var2"], ["Distance from downdraft center [m]","Velocity [m/s]"]);
            
            % Write table into csv file
            writetable(t, 'OBV_Li_radial_profile_table.csv')
        end

        % Button pushed function: ExportPlotButton_2
        function ExportPlotButton_2Pushed(app, event)
            % Create a temporary figure of the plot
            fig = figure;
            fig.Visible = 'off';
            figAxes = axes(fig);            
            allChildren = app.VerticalAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.VerticalAxes.XLim;
            figAxes.YLim = app.VerticalAxes.YLim;
            figAxes.ZLim = app.VerticalAxes.ZLim;
            figAxes.DataAspectRatio = app.VerticalAxes.DataAspectRatio;
            xlabel(figAxes, 'Velocity [m/s]');
            ylabel(figAxes, 'Height above ground [m]');
            title(figAxes, 'Vertical Profile of Velocity');

            % Save plot
            filename = imputfile;
            if filename ~= ""
                exportgraphics(fig, filename)
            end

            % Delete the temporary figure
            delete(fig);
        end

        % Button pushed function: ExportDataTableButton_2
        function ExportDataTableButton_2Pushed(app, event)
            % Define parameters for vertical profile
            z = 0:0.1:1000;
            u_mm = app.MaxhorizontalvelSlider_2.Value;
            D = app.JetDiameterSlider_2.Value * 1000;
            r = app.rSlider.Value * 1000;            
            r_dm = 1.473*D;
            eta_r = 1.134;
            xi_r = 0.28;
            delt = D * (eta_r - (r/r_dm) .* exp ( (1 - (r/r_dm).^(2*xi_r))./(2*xi_r)) );                      
            r_um = 1.007*D;
            u_mRS = exp(2.242*(1 - (r_um./r) - log(r./r_um) ) );
            gama = 0.261;
            z_m = delt *  exp((1/gama) * (gama + gama*lambertw(-1, -exp( -(gama+log(2))/gama)) + log(2)  ));                                   
            u_VS = (z./z_m).^gama .* exp(gama*(1-z./z_m));
            
            % Put y axis values into an array
            z = [0:0.1:1000];
            % Compute velocities and insert into arrays
            m = [compute_urz(app, u_mm, u_mRS, u_VS)];

            % Arrange arrays into a table
            ar = [m; z];
            t = array2table(ar.');
            t = renamevars(t, ["Var1", "Var2"], ["Magnitude [m/s]", "Height above ground [m]"]);

            % Write table into file
            [filename, folder] = uiputfile({'*.csv';'*.txt';'*.dat'});
            if filename ~= 0
                filenameFull = fullfile(folder, filename);
                writetable(t, filenameFull)
            end
        end

        % Close request function: UIFigure
        function OBVLiCloseRequest(app, event)
            % Reenable OBV: Li button
            app.CallingApp.Lietal2012Button.Enable = 'on';

            % Close OBV: Li window
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @OBVLiCloseRequest, true);
            app.HorizontalAxes = uiaxes(app.UIFigure);
            title(app.HorizontalAxes, 'Radial Profile of Velocity')
            xlabel(app.HorizontalAxes, 'Distance from downdraft center [m]')
            ylabel(app.HorizontalAxes, 'Velocity [m/s]')
            zlabel(app.HorizontalAxes, 'Z')
            app.VerticalAxes = uiaxes(app.UIFigure);
            title(app.VerticalAxes, 'Vertical Profile of Velocity')
            xlabel(app.VerticalAxes, 'Velocity [m/s]')
            ylabel(app.VerticalAxes, 'Height above ground [m]')
            zlabel(app.VerticalAxes, 'Z')
            app.Lietal2012Label = uilabel(app.UIFigure);
            app.Lietal2012Label.Text = 'Li et al. (2012)';
            app.RadialProfileModelParametersPanel = uipanel(app.UIFigure);
            app.RadialProfileModelParametersPanel.Title = 'Radial Profile: Model Parameters';
            app.MaximumhorizontalvelocitymsLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.MaximumhorizontalvelocitymsLabel.Text = 'Maximum horizontal velocity [m/s]';
            app.MaxhorizontalvelSlider = uislider(app.RadialProfileModelParametersPanel);
            app.MaxhorizontalvelSlider.Limits = [0 60];
            app.MaxhorizontalvelSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.MaxhorizontalvelSlider.Value = 40;
            app.MaxhorizontalvelField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.MaxhorizontalvelField.Limits = [0 60];
            app.MaxhorizontalvelField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.MaxhorizontalvelField.Value = 40;
            app.JetDiameterField = uieditfield(app.RadialProfileModelParametersPanel, 'numeric');
            app.JetDiameterField.Limits = [0 10];
            app.JetDiameterField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.JetDiameterField.Value = 1;
            app.JetDiameterkmLabel = uilabel(app.RadialProfileModelParametersPanel);
            app.JetDiameterkmLabel.Text = 'Jet Diameter [km]';
            app.JetDiameterSlider = uislider(app.RadialProfileModelParametersPanel);
            app.JetDiameterSlider.Limits = [0 10];
            app.JetDiameterSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.JetDiameterSlider.Value = 1;
            app.ExportPlotButton = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton.Text = {'Export Plot'; ''};
            app.ExportDataTableButton = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButtonPushed, true);
            app.ExportDataTableButton.Text = {'Export Data Table'; ''};
            app.VerticalProfileModelParametersPanel = uipanel(app.UIFigure);
            app.VerticalProfileModelParametersPanel.Title = 'Vertical Profile: Model Parameters';
            app.MaximumhorizontalvelocitymsLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.MaximumhorizontalvelocitymsLabel_2.Text = 'Maximum horizontal velocity [m/s]';
            app.MaxhorizontalvelSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.MaxhorizontalvelSlider_2.Limits = [0 60];
            app.MaxhorizontalvelSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.MaxhorizontalvelSlider_2.Value = 40;
            app.JetDiameterField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.JetDiameterField_2.Limits = [0 10];
            app.JetDiameterField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.JetDiameterField_2.Value = 1;
            app.JetDiameterkmLabel_2 = uilabel(app.VerticalProfileModelParametersPanel);
            app.JetDiameterkmLabel_2.Text = 'Jet Diameter [km]';
            app.JetDiameterSlider_2 = uislider(app.VerticalProfileModelParametersPanel);
            app.JetDiameterSlider_2.Limits = [0 10];
            app.JetDiameterSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.JetDiameterSlider_2.Value = 1;
            app.MaxhorizontalvelField_2 = uieditfield(app.VerticalProfileModelParametersPanel, 'numeric');
            app.MaxhorizontalvelField_2.Limits = [0 60];
            app.MaxhorizontalvelField_2.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.MaxhorizontalvelField_2.Value = 40;
            app.ExportPlotButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButton_2Pushed, true);
            app.ExportPlotButton_2.Text = {'Export Plot'; ''};
            app.ExportDataTableButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportDataTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportDataTableButton_2Pushed, true);
            app.ExportDataTableButton_2.Text = {'Export Data Table'; ''};
            app.Panel_2 = uipanel(app.UIFigure);
            app.zmLabel_2 = uilabel(app.Panel_2);
            app.zmLabel_2.Text = 'Distance from downdraft center [km]';
            app.rField = uieditfield(app.Panel_2, 'numeric');
            app.rField.Limits = [0 10];
            app.rField.ValueChangedFcn = createCallbackFcn(app, @FieldValue2Changed, true);
            app.rField.Value = 1;
            app.rSlider = uislider(app.Panel_2);
            app.rSlider.Limits = [0 10];
            app.rSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValue2Changed, true);
            app.rSlider.Value = 1;
            app.PlottingOptionsPanel = uipanel(app.UIFigure);
            app.PlottingOptionsPanel.Title = 'Plotting Options';
            app.FixedParametersCheckBox = uicheckbox(app.PlottingOptionsPanel);
            app.FixedParametersCheckBox.Text = 'Keep model parameters fixed';
            app.Panel = uipanel(app.UIFigure);
            app.zmLabel = uilabel(app.Panel);
            app.zmLabel.Text = 'Height above ground [m]';
            app.zSlider = uislider(app.Panel);
            app.zSlider.Limits = [0 1000];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.zSlider.Value = 100;
            app.zField = uieditfield(app.Panel, 'numeric');
            app.zField.Limits = [0 1000];
            app.zField.ValueChangedFcn = createCallbackFcn(app, @FieldValueChanged, true);
            app.zField.Value = 100;
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://doi.org/10.1016/j.jweia.2011.12.004';
            app.Hyperlink.Text = 'Corresponding Paper';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OBV_Li(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end